classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutonomousCarSimulatorUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveCurrentConfigurationButton  matlab.ui.container.Menu
        LoadConfigurationFileButton     matlab.ui.container.Menu
        PrintPathONMenu                 matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        AutonomousCarConfigurationsPanel  matlab.ui.container.Panel
        SetviaconfigurationsButton      matlab.ui.control.Button
        SetinitialandfinalconfigurationsButton  matlab.ui.control.Button
        ClearpreviousconfigurationsButton  matlab.ui.control.Button
        ClearprevioussimulationButton   matlab.ui.control.Button
        EventsPanel                     matlab.ui.container.Panel
        StoptrafficsignButton           matlab.ui.control.Button
        RadarButton                     matlab.ui.control.Button
        PedestriancrossingtrafficsignButton  matlab.ui.control.Button
        PedestriancrossingcustomButton  matlab.ui.control.Button
        TimesSpinnerLabel               matlab.ui.control.Label
        TimesSpinner                    matlab.ui.control.Spinner
        DurationsSpinnerLabel           matlab.ui.control.Label
        DurationsSpinner                matlab.ui.control.Spinner
        SpeedLimitKmhSliderLabel        matlab.ui.control.Label
        SpeedLimitKmhSlider             matlab.ui.control.Slider
        SpeedlimitedzoneButton          matlab.ui.control.Button
        StopEventsLabel                 matlab.ui.control.Label
        SpeedEventsLabel                matlab.ui.control.Label
        CrosswalkEventsLabel            matlab.ui.control.Label
        RedsSpinnerLabel                matlab.ui.control.Label
        RedsSpinner                     matlab.ui.control.Spinner
        GreensSpinnerLabel              matlab.ui.control.Label
        GreensSpinner                   matlab.ui.control.Spinner
        EnergyConsumptionPanel          matlab.ui.container.Panel
        EnergyBudgetWhEditFieldLabel    matlab.ui.control.Label
        EnergyBudgetJEditField          matlab.ui.control.EditField
        NavigationPanel                 matlab.ui.container.Panel
        SpeedometerKmhGaugeLabel        matlab.ui.control.Label
        SpeedometerKmhGauge             matlab.ui.control.Gauge
        BatteryStatusLabel              matlab.ui.control.Label
        BatteryStatusGauge              matlab.ui.control.Gauge
        TimesLabel                      matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        CollisionCounterGauge           matlab.ui.control.Label
        CollisionCounterLabel           matlab.ui.control.Label
        TimeseriesplotssPanel           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        LinearVelocityKmhTab            matlab.ui.container.Tab
        UIAxes2_2                       matlab.ui.control.UIAxes
        AngularVelocityradsTab          matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        KvgainTab                       matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        SimulationEnvironmentPanel      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        StatisticsPanel                 matlab.ui.container.Panel
        TravelledDistancemLabel         matlab.ui.control.Label
        AverageSpeedKmhLabel            matlab.ui.control.Label
        ETAsLabel                       matlab.ui.control.Label
        PowerConsumptionKWLabel         matlab.ui.control.Label
        EnergyConsumptionKWhLabel       matlab.ui.control.Label
        Label_td                        matlab.ui.control.Label
        Label_as                        matlab.ui.control.Label
        Label_eta                       matlab.ui.control.Label
        Label_pc                        matlab.ui.control.Label
        Label_ec                        matlab.ui.control.Label
        EstimatedRangemLabel            matlab.ui.control.Label
        Label_er                        matlab.ui.control.Label
        STARTSimulationButton           matlab.ui.control.Button
        Button_pause                    matlab.ui.control.Button
        Button_stop                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        img = imread('ist_gmaps.png');
        %img = evalin('base','img');
        scale = 0.4;
        via = [];
        eventList = [];
        dx = 20;
        dy = 20;
        UserData = struct();
        myTimer;
        counter = 0;
        start = 0;
        exit = 0;
        pause = 0;
        stop = 0;
        map = [];
        a=0;
        b=0;
        carVertices = [];
        k = 2;
        printPath = true;
        path;
    end
    
    
    methods (Access = public)
        
        function createEvent(app, eventFlag, par1, par2)
            f = figure();
            imshow(app.img);
            [x,y] = ginput(1);
            if ~validCoordinates(app, round(x*app.scale), round(y*app.scale))
                % inform user
                close(f);
                warndlg('Please select only points which belong to a road','Warning');
                return
            end
            app.eventList(:,end+1)=[round(x*app.scale); round(y*app.scale); eventFlag; par1; par2];
            switch eventFlag
                case -2
                    label = "stop";
                case -3
                    label = "speed limit";
                    label = strcat("radar (", string(round(par1)), ")");
                case -4
                    label = "crosswalk";
                case -5
                    label = strcat("crosswalk (", string(par1), ",", string(par2), ")");
            end
            scatter(app.UIAxes, app.eventList(1,end)/app.scale, app.eventList(2,end)/app.scale, 'magenta');
            text(app.UIAxes, (app.eventList(1,end)/app.scale)+app.dx, (app.eventList(2,end)/app.scale)+app.dy, label);
            close(f);            
        end
        
        function [a,b, carVertices] = draw(app, xx,yy,goal,theta_car)
            %%%%%%%%%%%%%%%%%%%%%%%%
            % dimensions of the car
            %
            car_length = 3.332;
            car_width = 1.508;
            car_wheelbase = 2.2;
            car_length_out_wheelbase = car_length - car_wheelbase;
            % assume that the length out-of-wheelbase is identical at front and back of
            % the car
            a1 = car_length_out_wheelbase / 2;
            %  car polygon constructed ccw
            car_polygon = [ -a1, -car_width/2;
                car_wheelbase + a1, -car_width/2;
                car_wheelbase + a1, car_width/2;
                -1, car_width/2 ];
            
            
            app.a = plot(app.UIAxes,0,0);
            app.b = plot(app.UIAxes,0,0);
            aux = find(xx == -1);
            k = aux(1)-1;
            flag = theta_car;
            if size(theta_car,2) == 2
                theta_car = atan2(theta_car(1),theta_car(2));
            end
            
            if goal == 0
                theta_car = atan2(yy(k)-yy(k-1), xx(k)-xx(k-1));
            end
            if k > 1 && size(aux,1) ~= 0
                scale = 0.2;
                
                for p=1:4,
                    rot_car_polygon(p,:) = ([cos(theta_car), -sin(theta_car); sin(theta_car), cos(theta_car)]*car_polygon(p,:)')';
                    carVertices(p,1) = rot_car_polygon(p,1)/scale + xx(k);
                    carVertices(p,2) = rot_car_polygon(p,2)/scale + yy(k);
                end
                app.a = plot(app.UIAxes,round((rot_car_polygon(:,1)/scale+xx(k))), round((rot_car_polygon(:,2)/scale+yy(k))),'color','r');
                app.b = plot(app.UIAxes,round(xx(1:k)),round(yy(1:k)),'color','r');
                drawnow
            end
            
            if size(aux,1) == 0
                k = xx(end);
                scale = 0.2;
                
                for p=1:4,
                    rot_car_polygon(p,:) = ([cos(theta_car), -sin(theta_car); sin(theta_car), cos(theta_car)]*car_polygon(p,:)')';
                    carVertices(p,1) = rot_car_polygon(p,1)/scale + xx(k);
                    carVertices(p,2) = rot_car_polygon(p,2)/scale + yy(k);
                end
                app.a = plot(app.UIAxes,round((rot_car_polygon(:,1)/scale+xx(k))), round((rot_car_polygon(:,2)/scale+yy(k))),'color','r');
                app.b = plot(app.UIAxes,round(xx(1:k)),round(yy(1:k)),'color','r');
                drawnow
            end
%             pause(0.01);
            if goal == 0 || size(flag, 2) == 1
                delete(app.a);
                delete(app.b);
            end
            a = app.a;
            b = app.b;
        end
        
        function plotLiveSpeed(app,i,h,u)
            aux = find(u == -1);
            if size(aux, 2) ~= 0
                plot(app.UIAxes2_2, (1:i-1)*h, u(1,1:i-1)*0.2*3.6);
            end
        end
    
        function plotLiveAng(app,i,h,u)
            aux = find(u == -1);
            if size(aux, 2) ~= 0
                plot(app.UIAxes3, (1:i-1)*h, u(1,1:i-1)*0.2*3.6);
            end
        end
        
        function plotLiveEnergy(app,i,h,E)
            aux = find(E == -1);
            if size(aux, 2) ~= 0
                plot(app.UIAxes2, (1:i-1)*h, E(1:i-1));
            end
        end
        
        function plotLiveGain(app,K,i,h)
            plot(app.UIAxes4, (1:i)*h, K(1:end));
        end
        
        function bool = validCoordinates(app,x,y)
            bool = true;
            if(app.map(y,x) == -1)
                bool = false;
            end
        end
        
        function clearPreviousSimulation(app,a,b)
            delete(a);
            delete(b);
        end
    
        function warning(app)
            warndlg('Invalid Points! Impossible Path Requested!','Warning');
        end
        
        function plotPath(app,x,y)
            app.path = plot(app.UIAxes,x,y,'Color','b');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow(app.img,'Parent',app.UIAxes);
            hold(app.UIAxes,"on");  
            app.Button_pause.Enable = 0;
            app.Button_stop.Enable = 0;
            app.STARTSimulationButton.Enable = 0;
            app.SetviaconfigurationsButton.Enable = 0;
            app.StoptrafficsignButton.Enable = 0;
            app.RadarButton.Enable = 0;
            app.PedestriancrossingtrafficsignButton.Enable = 0;
            app.PedestriancrossingcustomButton.Enable = 0;
            app.ClearpreviousconfigurationsButton.Enable = 0;
            app.ClearprevioussimulationButton.Enable = 0;
            app.SpeedlimitedzoneButton.Enable = 0;
            app.SaveCurrentConfigurationButton.Enable = 0;
            
            file = load('map.mat'); app.map = file.map;
        end

        % Button pushed function: 
        % SetinitialandfinalconfigurationsButton
        function SetinitialandfinalconfigurationsButtonPushed(app, event)
            f = figure();
            imshow(app.img);
            [x,y] = ginput(2);
            for k=1:2
                if ~validCoordinates(app, round(x(k)*app.scale), round(y(k)*app.scale))
                    % inform user
                    close(f);
                    warndlg('Please select only points which belong to a road','Warning');
                    return
                end
                app.via(:,k)=[round(x(k)*app.scale);round(y(k)*app.scale)];
            end
            scatter(app.UIAxes, app.via(1,1)/app.scale, app.via(2,1)/app.scale, 'green', 'filled');
            text(app.UIAxes, (app.via(1,1)/app.scale)+app.dx, (app.via(2,1)/app.scale)+app.dy, "start");
            scatter(app.UIAxes, app.via(1,2)/app.scale, app.via(2,2)/app.scale, 'red', 'filled');
            text(app.UIAxes, (app.via(1,2)/app.scale)+app.dx, (app.via(2,2)/app.scale)+app.dy, "end");
            close(f);
            app.stop = 0;
            app.STARTSimulationButton.Enable = 1;
            app.SetviaconfigurationsButton.Enable = 1;
            app.StoptrafficsignButton.Enable = 1;
            app.RadarButton.Enable = 1;
            app.PedestriancrossingtrafficsignButton.Enable = 1;
            app.PedestriancrossingcustomButton.Enable = 1;
            app.ClearpreviousconfigurationsButton.Enable = 1;
            app.SpeedlimitedzoneButton.Enable = 1;
            app.SaveCurrentConfigurationButton.Enable = 1;
        end

        % Button pushed function: SetviaconfigurationsButton
        function SetviaconfigurationsButtonPushed(app, event)
            f = figure();
            imshow(app.img);
            final_cfg = app.via(:,end);
            button = 1;
            aux = size(app.via,2);
            k = size(app.via, 2);
            while button==1
                [x(k),y(k),button] = ginput(1);
                if button == 1
                    if ~validCoordinates(app, round(x(k)*app.scale), round(y(k)*app.scale))
                        % inform user
                        close(f);
                        warndlg('Please select only points which belong to a road','Warning');
                        return
                    end
                end
                app.via(:,k)=[round(x(k)*app.scale);round(y(k)*app.scale)];
                k = k + 1;
            end
            for i=aux:size(app.via,2)-1
                scatter(app.UIAxes, app.via(1,i)/app.scale, app.via(2,i)/app.scale, 'blue', 'filled');
                label = strcat("via ", string(i-1));
                text(app.UIAxes, (app.via(1,i)/app.scale)+app.dx, (app.via(2,i)/app.scale)+app.dy, label);
            end
            app.via(:,size(app.via,2)) = final_cfg;
            close(f);
            %assignin('base','fcnStatus',app.via)
        end

        % Button pushed function: STARTSimulationButton
        function STARTSimulationButtonPushed(app, event)
%             assignin('base', 'battery', str2double(app.EnergyBudgetJEditField.Value));
%             assignin('base','via', app.via);
%             assignin('base','eventList', app.eventList);
            if str2double(app.EnergyBudgetJEditField.Value) < 0
                warndlg('Please choose a valid value for the energy budget','Warning');
            else
                app.start = 1;
                app.STARTSimulationButton.Text = "Simulation in progress";
                app.STARTSimulationButton.FontColor = [0.93,0.69,0.13];
                app.STARTSimulationButton.Enable = 0;
                app.SetviaconfigurationsButton.Enable = 0;
                app.StoptrafficsignButton.Enable = 0;
                app.RadarButton.Enable = 0;
                app.PedestriancrossingtrafficsignButton.Enable = 0;
                app.PedestriancrossingcustomButton.Enable = 0;
                app.SetinitialandfinalconfigurationsButton.Enable = 0;
                app.ClearpreviousconfigurationsButton.Enable = 0;
                app.ClearprevioussimulationButton.Enable = 0;
                app.SpeedlimitedzoneButton.Enable = 0;
                app.Button_pause.Enable = 1;
                app.Button_stop.Enable = 1;
                app.pause = 0;
                app.stop = 0;
                app.SaveCurrentConfigurationButton.Enable = 0;
                app.LoadConfigurationFileButton.Enable = 0;
            end
        end

        % Button pushed function: ClearpreviousconfigurationsButton
        function ClearpreviousconfigurationsButtonPushed(app, event)
            app.via = [];
            app.eventList = [];
            cla(app.UIAxes, "reset");
            imshow(app.img,'Parent',app.UIAxes);
            hold(app.UIAxes,"on");
            app.STARTSimulationButton.Enable = 0;
            app.Button_pause.Enable = 0;
            app.Button_stop.Enable = 0;
            app.SetviaconfigurationsButton.Enable = 0;
            app.StoptrafficsignButton.Enable = 0;
            app.RadarButton.Enable = 0;
            app.PedestriancrossingtrafficsignButton.Enable = 0;
            app.PedestriancrossingcustomButton.Enable = 0;
            app.ClearpreviousconfigurationsButton.Enable = 0;
            app.ClearprevioussimulationButton.Enable = 0;
            app.SpeedlimitedzoneButton.Enable = 0;
            app.SaveCurrentConfigurationButton.Enable = 0;
            app.SetinitialandfinalconfigurationsButton.Enable = 1;
            app.EnergyBudgetJEditField.Value = num2str(500);
            app.LoadConfigurationFileButton.Enable = 1;
        end

        % Button pushed function: StoptrafficsignButton
        function StoptrafficsignButtonPushed(app, event)
            app.createEvent(-2, 0, 0);
        end

        % Button pushed function: RadarButton
        function RadarButtonPushed(app, event)
            speedLimit = app.SpeedLimitKmhSlider.Value;
            app.createEvent(-3, speedLimit, 0);
        end

        % Button pushed function: 
        % PedestriancrossingtrafficsignButton
        function PedestriancrossingtrafficsignButtonPushed(app, event)
            par1 = app.RedsSpinner.Value;
            par2 = app.GreensSpinner.Value;
            app.createEvent(-4, par1, par2);
        end

        % Button pushed function: PedestriancrossingcustomButton
        function PedestriancrossingcustomButtonPushed(app, event)
            time = app.TimesSpinner.Value;
            duration = app.DurationsSpinner.Value;
            app.createEvent(-5, time, duration);
        end

        % Close request function: AutonomousCarSimulatorUIFigure
        function AutonomousCarSimulatorUIFigureCloseRequest(app, event)
            app.exit = 1;
            app.counter = app.counter + 1;
            if app.counter > 1
                delete(app)
            end
        end

        % Button pushed function: Button_pause
        function Button_pausePushed(app, event)
            if app.pause == 0
                app.pause = 1;
                app.Button_pause.Icon = "play1.png";
            else
                app.pause = 0;
                app.Button_pause.Icon = "icon-pause-512.png";
            end
        end

        % Button pushed function: Button_stop
        function Button_stopPushed(app, event)
            app.stop = 1;
            if app.pause == 1
                app.pause = 0;
                app.Button_pause.Icon = "icon-pause-512.png";
            end
        end

        % Button pushed function: ClearprevioussimulationButton
        function ClearprevioussimulationButtonPushed(app, event)
            app.clearPreviousSimulation(app.a, app.b);
            delete(app.path);
        end

        % Button pushed function: SpeedlimitedzoneButton
        function SpeedlimitedzoneButtonPushed(app, event)
              f = figure();
            imshow(app.img);
            [x,y] = ginput(2);
            speedLimit = app.SpeedLimitKmhSlider.Value;
            for k=1:2
                if ~validCoordinates(app, round(x(k)*app.scale), round(y(k)*app.scale))
                    % inform user
                    close(f);
                    warndlg('Please select only points which belong to a road','Warning');
                    return
                end
                app.eventList(:,end+1)=[round(x(k)*app.scale);round(y(k)*app.scale);-5-k;speedLimit;0];
            end
             scatter(app.UIAxes, app.eventList(1,end)/app.scale, app.eventList(2,end)/app.scale, 'magenta');
            
            label = strcat("speed lim. start (", string(round(speedLimit)), ")");
            text(app.UIAxes, (app.eventList(1,end-1)/app.scale)+app.dx, (app.eventList(2,end-1)/app.scale)+app.dy, label);
            scatter(app.UIAxes, app.eventList(1,end-1)/app.scale, app.eventList(2,end-1)/app.scale, 'magenta');
            label = strcat("speed lim. end (", string(round(speedLimit)), ")");
            text(app.UIAxes, (app.eventList(1,end)/app.scale)+app.dx, (app.eventList(2,end)/app.scale)+app.dy, label);
            close(f);

        end

        % Callback function
        function SaveCurrentConfigurationButtonPushed(app, event)
            filter = {'*.mat'};
            [file, path] = uiputfile(filter);
            if path ~= 0
                eventList = app.eventList;
                via = app.via;
                battery = str2double(app.EnergyBudgetJEditField.Value);
                save(strcat(path,file),'eventList', 'via', 'battery');
            end
            drawnow;
            app.AutonomousCarSimulatorUIFigure.Visible = 'off';
            app.AutonomousCarSimulatorUIFigure.Visible = 'on';
        end

        % Callback function
        function LoadConfigurationFileButtonPushed(app, event)
            
            filter = {'*.mat'};
            [file, path] = uigetfile(filter);
            drawnow;
            %FocusUIFigure(app.AutonomousCarSimulatorUIFigure)
            app.AutonomousCarSimulatorUIFigure.Visible = 'off';
            app.AutonomousCarSimulatorUIFigure.Visible = 'on';
            if path ~= 0
                cla(app.UIAxes, "reset");
                imshow(app.img,'Parent',app.UIAxes);
                hold(app.UIAxes,"on");
                data = load(strcat(path,file));
                app.via = data.via;
                app.eventList = data.eventList;
                app.EnergyBudgetJEditField.Value = num2str(data.battery);
                
                
                for i=2:size(app.via, 2)-1
                    scatter(app.UIAxes, app.via(1,i)/app.scale, app.via(2,i)/app.scale, 'blue', 'filled');
                    label = strcat("via ", string(i-1));
                    text(app.UIAxes, (app.via(1,i)/app.scale)+app.dx, (app.via(2,i)/app.scale)+app.dy, label);
                end
                scatter(app.UIAxes, app.via(1,1)/app.scale, app.via(2,1)/app.scale, 'green', 'filled');
                text(app.UIAxes, (app.via(1,1)/app.scale)+app.dx, (app.via(2,1)/app.scale)+app.dy, "start");
                scatter(app.UIAxes, app.via(1,end)/app.scale, app.via(2,end)/app.scale, 'red', 'filled');
                text(app.UIAxes, (app.via(1,end)/app.scale)+app.dx, (app.via(2,end)/app.scale)+app.dy, "end");
                for i=1:size(app.eventList, 2)
                    switch app.eventList(3,i)
                        case -2
                            label = "stop";
                        case -3
                            label = "speed limit";
                            label = strcat("radar (", string(round(app.eventList(4,i))), ")");
                        case -4
                            label = "crosswalk";
                        case -5
                            label = strcat("crosswalk (", string(app.eventList(4,i)), ",", string(app.eventList(5,i)), ")");
                        case -6
                            label = strcat("speed lim. start (", string(round(app.eventList(4,i))), ")");
                        case -7
                            label = strcat("speed lim. end (", string(round(app.eventList(4,i))), ")");
                    end
                    scatter(app.UIAxes, app.eventList(1,i)/app.scale, app.eventList(2,i)/app.scale, 'magenta');
                    text(app.UIAxes, (app.eventList(1,i)/app.scale)+app.dx, (app.eventList(2,i)/app.scale)+app.dy, label);
                end
                
                app.STARTSimulationButton.Enable = 1;
                app.SetviaconfigurationsButton.Enable = 1;
                app.StoptrafficsignButton.Enable = 1;
                app.RadarButton.Enable = 1;
                app.SpeedlimitedzoneButton.Enable = 1;
                app.PedestriancrossingtrafficsignButton.Enable = 1;
                app.PedestriancrossingcustomButton.Enable = 1;
                app.SetinitialandfinalconfigurationsButton.Enable = 1;
                app.ClearpreviousconfigurationsButton.Enable = 1;
                app.ClearprevioussimulationButton.Enable = 1;
                app.SaveCurrentConfigurationButton.Enable = 1;
                
                app.SetinitialandfinalconfigurationsButton.Enable = 0;
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            myicon = imread('ist.png');
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            msgbox({'Robotics project developed in the 2nd semester of 2020/2021';'Work done by:';'\bullet António Antunes'; '\bullet Francisco Velez'; '\bullet Pedro Salgueiro'},CreateStruct);
        end

        % Menu selected function: SaveCurrentConfigurationButton
        function SaveCurrentConfigurationButtonSelected(app, event)
            filter = {'exampleConfigurations/*.mat'};
            [file, path] = uiputfile(filter);
            if path ~= 0
                eventList = app.eventList;
                via = app.via;
                battery = str2double(app.EnergyBudgetJEditField.Value);
                save(strcat(path,file),'eventList', 'via', 'battery');
            end
            drawnow;
            app.AutonomousCarSimulatorUIFigure.Visible = 'off';
            app.AutonomousCarSimulatorUIFigure.Visible = 'on';
        end

        % Menu selected function: LoadConfigurationFileButton
        function LoadConfigurationFileButtonSelected(app, event)
            filter = {'exampleConfigurations/*.mat'};
            [file, path] = uigetfile(filter);
            drawnow;
            %FocusUIFigure(app.AutonomousCarSimulatorUIFigure)
            app.AutonomousCarSimulatorUIFigure.Visible = 'off';
            app.AutonomousCarSimulatorUIFigure.Visible = 'on';
            if path ~= 0
                cla(app.UIAxes, "reset");
                imshow(app.img,'Parent',app.UIAxes);
                hold(app.UIAxes,"on");
                data = load(strcat(path,file));
                app.via = data.via;
                app.eventList = data.eventList;
                app.EnergyBudgetJEditField.Value = num2str(data.battery);
                
                
                for i=2:size(app.via, 2)-1
                    scatter(app.UIAxes, app.via(1,i)/app.scale, app.via(2,i)/app.scale, 'blue', 'filled');
                    label = strcat("via ", string(i-1));
                    text(app.UIAxes, (app.via(1,i)/app.scale)+app.dx, (app.via(2,i)/app.scale)+app.dy, label);
                end
                scatter(app.UIAxes, app.via(1,1)/app.scale, app.via(2,1)/app.scale, 'green', 'filled');
                text(app.UIAxes, (app.via(1,1)/app.scale)+app.dx, (app.via(2,1)/app.scale)+app.dy, "start");
                scatter(app.UIAxes, app.via(1,end)/app.scale, app.via(2,end)/app.scale, 'red', 'filled');
                text(app.UIAxes, (app.via(1,end)/app.scale)+app.dx, (app.via(2,end)/app.scale)+app.dy, "end");
                for i=1:size(app.eventList, 2)
                    switch app.eventList(3,i)
                        case -2
                            label = "stop";
                        case -3
                            label = "speed limit";
                            label = strcat("radar (", string(round(app.eventList(4,i))), ")");
                        case -4
                            label = "crosswalk";
                        case -5
                            label = strcat("crosswalk (", string(app.eventList(4,i)), ",", string(app.eventList(5,i)), ")");
                        case -6
                            label = strcat("speed lim. start (", string(round(app.eventList(4,i))), ")");
                        case -7
                            label = strcat("speed lim. end (", string(round(app.eventList(4,i))), ")");
                    end
                    scatter(app.UIAxes, app.eventList(1,i)/app.scale, app.eventList(2,i)/app.scale, 'magenta');
                    text(app.UIAxes, (app.eventList(1,i)/app.scale)+app.dx, (app.eventList(2,i)/app.scale)+app.dy, label);
                end
                
                app.STARTSimulationButton.Enable = 1;
                app.SetviaconfigurationsButton.Enable = 1;
                app.StoptrafficsignButton.Enable = 1;
                app.RadarButton.Enable = 1;
                app.SpeedlimitedzoneButton.Enable = 1;
                app.PedestriancrossingtrafficsignButton.Enable = 1;
                app.PedestriancrossingcustomButton.Enable = 1;
                app.SetinitialandfinalconfigurationsButton.Enable = 1;
                app.ClearpreviousconfigurationsButton.Enable = 1;
                app.ClearprevioussimulationButton.Enable = 1;
                app.SaveCurrentConfigurationButton.Enable = 1;
                
                app.SetinitialandfinalconfigurationsButton.Enable = 0;
            end
        end

        % Menu selected function: PrintPathONMenu
        function PrintPathONMenuSelected(app, event)
            if app.printPath 
                app.PrintPathONMenu.Text = "Print Path: OFF";
                app.printPath = false;
            else
                app.PrintPathONMenu.Text = "Print Path: ON";
                app.printPath = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutonomousCarSimulatorUIFigure = uifigure('Visible', 'off');
            app.AutonomousCarSimulatorUIFigure.Name = 'Autonomous Car Simulator';
            app.AutonomousCarSimulatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @AutonomousCarSimulatorUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.AutonomousCarSimulatorUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveCurrentConfigurationButton = uimenu(app.FileMenu);
            app.SaveCurrentConfigurationButton.MenuSelectedFcn = createCallbackFcn(app, @SaveCurrentConfigurationButtonSelected, true);
            app.SaveCurrentConfigurationButton.Text = 'Save Current Configuration';
            app.LoadConfigurationFileButton = uimenu(app.FileMenu);
            app.LoadConfigurationFileButton.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigurationFileButtonSelected, true);
            app.LoadConfigurationFileButton.Text = 'Load Configuration';
            app.PrintPathONMenu = uimenu(app.FileMenu);
            app.PrintPathONMenu.MenuSelectedFcn = createCallbackFcn(app, @PrintPathONMenuSelected, true);
            app.PrintPathONMenu.Text = 'Print Path: ON';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.AutonomousCarConfigurationsPanel = uipanel(app.AutonomousCarSimulatorUIFigure);
            app.AutonomousCarConfigurationsPanel.Title = 'Autonomous Car Configurations';
            app.SetviaconfigurationsButton = uibutton(app.AutonomousCarConfigurationsPanel, 'push');
            app.SetviaconfigurationsButton.ButtonPushedFcn = createCallbackFcn(app, @SetviaconfigurationsButtonPushed, true);
            app.SetviaconfigurationsButton.Text = 'Set via configurations';
            app.SetinitialandfinalconfigurationsButton = uibutton(app.AutonomousCarConfigurationsPanel, 'push');
            app.SetinitialandfinalconfigurationsButton.ButtonPushedFcn = createCallbackFcn(app, @SetinitialandfinalconfigurationsButtonPushed, true);
            app.SetinitialandfinalconfigurationsButton.Text = 'Set initial and final configurations';
            app.ClearpreviousconfigurationsButton = uibutton(app.AutonomousCarConfigurationsPanel, 'push');
            app.ClearpreviousconfigurationsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearpreviousconfigurationsButtonPushed, true);
            app.ClearpreviousconfigurationsButton.Text = 'Clear previous configurations';
            app.ClearprevioussimulationButton = uibutton(app.AutonomousCarConfigurationsPanel, 'push');
            app.ClearprevioussimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ClearprevioussimulationButtonPushed, true);
            app.ClearprevioussimulationButton.Text = 'Clear previous simulation';
            app.EventsPanel = uipanel(app.AutonomousCarSimulatorUIFigure);
            app.EventsPanel.Title = 'Events';
            app.StoptrafficsignButton = uibutton(app.EventsPanel, 'push');
            app.StoptrafficsignButton.ButtonPushedFcn = createCallbackFcn(app, @StoptrafficsignButtonPushed, true);
            app.StoptrafficsignButton.Text = 'Stop traffic sign';
            app.RadarButton = uibutton(app.EventsPanel, 'push');
            app.RadarButton.ButtonPushedFcn = createCallbackFcn(app, @RadarButtonPushed, true);
            app.RadarButton.Text = 'Speed radar';
            app.PedestriancrossingtrafficsignButton = uibutton(app.EventsPanel, 'push');
            app.PedestriancrossingtrafficsignButton.ButtonPushedFcn = createCallbackFcn(app, @PedestriancrossingtrafficsignButtonPushed, true);
            app.PedestriancrossingtrafficsignButton.Text = 'Pedestrian crossing traffic sign';
            app.PedestriancrossingcustomButton = uibutton(app.EventsPanel, 'push');
            app.PedestriancrossingcustomButton.ButtonPushedFcn = createCallbackFcn(app, @PedestriancrossingcustomButtonPushed, true);
            app.PedestriancrossingcustomButton.Text = 'Pedestrian crossing custom';
            app.TimesSpinnerLabel = uilabel(app.EventsPanel);
            app.TimesSpinnerLabel.Text = 'Time (s)';
            app.TimesSpinner = uispinner(app.EventsPanel);
            app.TimesSpinner.Limits = [1 Inf];
            app.TimesSpinner.Value = 1;
            app.DurationsSpinnerLabel = uilabel(app.EventsPanel);
            app.DurationsSpinnerLabel.Text = 'Duration (s)';
            app.DurationsSpinner = uispinner(app.EventsPanel);
            app.DurationsSpinner.Limits = [1 Inf];
            app.DurationsSpinner.Value = 5;
            app.SpeedLimitKmhSliderLabel = uilabel(app.EventsPanel);
            app.SpeedLimitKmhSliderLabel.Text = {'Speed '; ' Limit'; '(Km/h)'};
            app.SpeedLimitKmhSlider = uislider(app.EventsPanel);
            app.SpeedLimitKmhSlider.Limits = [0 25];
            app.SpeedLimitKmhSlider.Value = 20;
            app.SpeedlimitedzoneButton = uibutton(app.EventsPanel, 'push');
            app.SpeedlimitedzoneButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedlimitedzoneButtonPushed, true);
            app.SpeedlimitedzoneButton.Text = 'Speed limited zone';
            app.StopEventsLabel = uilabel(app.EventsPanel);
            app.StopEventsLabel.Text = 'Stop Events';
            app.SpeedEventsLabel = uilabel(app.EventsPanel);
            app.SpeedEventsLabel.Text = 'Speed Events';
            app.CrosswalkEventsLabel = uilabel(app.EventsPanel);
            app.CrosswalkEventsLabel.Text = 'Crosswalk Events';
            app.RedsSpinnerLabel = uilabel(app.EventsPanel);
            app.RedsSpinnerLabel.Text = 'Red (s)';
            app.RedsSpinner = uispinner(app.EventsPanel);
            app.RedsSpinner.Limits = [1 Inf];
            app.RedsSpinner.Value = 15;
            app.GreensSpinnerLabel = uilabel(app.EventsPanel);
            app.GreensSpinnerLabel.Text = 'Green (s)';
            app.GreensSpinner = uispinner(app.EventsPanel);
            app.GreensSpinner.Limits = [1 Inf];
            app.GreensSpinner.Value = 10;
            app.EnergyConsumptionPanel = uipanel(app.AutonomousCarSimulatorUIFigure);
            app.EnergyConsumptionPanel.Title = 'Energy Consumption';
            app.EnergyBudgetWhEditFieldLabel = uilabel(app.EnergyConsumptionPanel);
            app.EnergyBudgetWhEditFieldLabel.Text = 'Energy Budget (Wh)';
            app.EnergyBudgetJEditField = uieditfield(app.EnergyConsumptionPanel, 'text');
            app.EnergyBudgetJEditField.Value = '500';
            app.NavigationPanel = uipanel(app.AutonomousCarSimulatorUIFigure);
            app.NavigationPanel.Title = 'Navigation';
            app.SpeedometerKmhGaugeLabel = uilabel(app.NavigationPanel);
            app.SpeedometerKmhGaugeLabel.Text = 'Speedometer (Km/h)';
            app.SpeedometerKmhGauge = uigauge(app.NavigationPanel, 'circular');
            app.SpeedometerKmhGauge.Limits = [0 50];
            app.BatteryStatusLabel = uilabel(app.NavigationPanel);
            app.BatteryStatusLabel.Text = 'Battery Status (%)';
            app.BatteryStatusGauge = uigauge(app.NavigationPanel, 'circular');
            app.TimesLabel = uilabel(app.NavigationPanel);
            app.TimesLabel.Text = 'Time (s)';
            app.sLabel = uilabel(app.NavigationPanel);
            app.sLabel.Text = '0';
            app.CollisionCounterGauge = uilabel(app.NavigationPanel);
            app.CollisionCounterGauge.Text = '0';
            app.CollisionCounterLabel = uilabel(app.NavigationPanel);
            app.CollisionCounterLabel.Text = 'Collision Counter';
            app.TimeseriesplotssPanel = uipanel(app.AutonomousCarSimulatorUIFigure);
            app.TimeseriesplotssPanel.Title = 'Time-series plots (s)';
            app.TabGroup = uitabgroup(app.TimeseriesplotssPanel);
            app.LinearVelocityKmhTab = uitab(app.TabGroup);
            app.LinearVelocityKmhTab.Title = 'Linear Velocity (Km/h)';
            app.UIAxes2_2 = uiaxes(app.LinearVelocityKmhTab);
            app.AngularVelocityradsTab = uitab(app.TabGroup);
            app.AngularVelocityradsTab.Title = 'Angular Velocity (rad/s)';
            app.UIAxes3 = uiaxes(app.AngularVelocityradsTab);
            app.KvgainTab = uitab(app.TabGroup);
            app.KvgainTab.Title = 'Kv gain';
            app.UIAxes4 = uiaxes(app.KvgainTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes2 = uiaxes(app.TimeseriesplotssPanel);
            title(app.UIAxes2, 'Energy Consumption (J)')
            app.SimulationEnvironmentPanel = uipanel(app.AutonomousCarSimulatorUIFigure);
            app.SimulationEnvironmentPanel.Title = 'Simulation Environment';
            app.UIAxes = uiaxes(app.SimulationEnvironmentPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.StatisticsPanel = uipanel(app.AutonomousCarSimulatorUIFigure);
            app.StatisticsPanel.Title = 'Statistics';
            app.TravelledDistancemLabel = uilabel(app.StatisticsPanel);
            app.TravelledDistancemLabel.Text = 'Travelled Distance (m)';
            app.AverageSpeedKmhLabel = uilabel(app.StatisticsPanel);
            app.AverageSpeedKmhLabel.Text = 'Average Speed (Km/h)';
            app.ETAsLabel = uilabel(app.StatisticsPanel);
            app.ETAsLabel.Text = 'ETA (s)';
            app.PowerConsumptionKWLabel = uilabel(app.StatisticsPanel);
            app.PowerConsumptionKWLabel.Text = 'Power Consumption (KW)';
            app.EnergyConsumptionKWhLabel = uilabel(app.StatisticsPanel);
            app.EnergyConsumptionKWhLabel.Text = 'Energy Consumption (KWh)';
            app.Label_td = uilabel(app.StatisticsPanel);
            app.Label_td.Text = '0';
            app.Label_as = uilabel(app.StatisticsPanel);
            app.Label_as.Text = '0';
            app.Label_eta = uilabel(app.StatisticsPanel);
            app.Label_eta.Text = '0';
            app.Label_pc = uilabel(app.StatisticsPanel);
            app.Label_pc.Text = '0';
            app.Label_ec = uilabel(app.StatisticsPanel);
            app.Label_ec.Text = '0';
            app.EstimatedRangemLabel = uilabel(app.StatisticsPanel);
            app.EstimatedRangemLabel.Text = 'Estimated Range (m)';
            app.Label_er = uilabel(app.StatisticsPanel);
            app.Label_er.Text = '0';
            app.STARTSimulationButton = uibutton(app.AutonomousCarSimulatorUIFigure, 'push');
            app.STARTSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSimulationButtonPushed, true);
            app.STARTSimulationButton.Text = 'START Simulation';
            app.Button_pause = uibutton(app.AutonomousCarSimulatorUIFigure, 'push');
            app.Button_pause.ButtonPushedFcn = createCallbackFcn(app, @Button_pausePushed, true);
            app.Button_pause.Icon = 'icon-pause-512.png';
            app.Button_pause.Text = '';
            app.Button_stop = uibutton(app.AutonomousCarSimulatorUIFigure, 'push');
            app.Button_stop.ButtonPushedFcn = createCallbackFcn(app, @Button_stopPushed, true);
            app.Button_stop.Icon = 'media-stop-512.png';
            app.Button_stop.Text = '';
            % Show the figure after all components are created
            app.AutonomousCarSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutonomousCarSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutonomousCarSimulatorUIFigure)
        end
    end
end